classdef BandMath < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DropDown             matlab.ui.control.DropDown
        ListBox              matlab.ui.control.ListBox
        InfoTextArea         matlab.ui.control.TextArea
        NextDoneButton       matlab.ui.control.Button
        VarTextArea          matlab.ui.control.TextArea
        FormulaTextArea      matlab.ui.control.TextArea
        SelectedVarTextArea  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    
    properties (Access = public)
        vars % variables used in equation
        dataStruct % all data
        bandMathFunction % function to execute
        varcnt = 1 % count of variables used
        bandMathImage = [] % output image of math
        displayPressed = 0 % check if display was pressed and next variable needs to be chosen
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SpectralAnalysisApp, vars, bandMathFunction, dataStruct)
            app.CallingApp = SpectralAnalysisApp;
            app.FormulaTextArea.Value = char(['in ', char(bandMathFunction)]);
            app.VarTextArea.Value = char(vars{1});
            app.DropDown.Items = SpectralAnalysisApp.DropDown.Items;
            app.DropDown.Value = SpectralAnalysisApp.DropDown.Value;
            app.ListBox.Items = SpectralAnalysisApp.ListBox.Items;
            app.ListBox.Value = {};
            app.dataStruct = dataStruct;
            app.vars = vars;
            app.bandMathFunction = bandMathFunction;
            
            % asign first band to first variable in case user press next
            % before selection
            value = app.DropDown.Value;
            list = app.DropDown.Items;
            idx_file = find(strcmp(value,list)) + 1;
                
            img = app.dataStruct(idx_file).data;
            app.vars{1,2} = img(:,:,1);
            app.SelectedVarTextArea.Value = [char(app.vars{1,1}),': ',char(app.dataStruct(idx_file).bands(1))];
            
            if ~strcmp(app.DropDown.Items{end},'Current displayed image')
                app.DropDown.Items{end+1} = 'Current displayed image';
            end
            
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            % get index of selected file
            value = app.DropDown.Value;
            list = app.DropDown.Items;
            idx_file = find(strcmp(value,list));
            try
                app.ListBox.Items = app.dataStruct(idx_file+1).bands; 
            catch
                app.ListBox.Items = app.dataStruct(1).bands;
            end
            
        end

        % Button pushed function: NextDoneButton
        function NextDoneButtonPushed(app, event)
            app.varcnt = app.varcnt + 1;
            app.displayPressed = 0;
            
            try
            
                if app.varcnt <= size(app.vars,1)
                    app.VarTextArea.Value = char(app.vars{app.varcnt,1});
                    app.CallingApp.close_bandmath_app = 0;
                else
                    if app.varcnt == 3
                        mathImage = app.bandMathFunction(app.vars{1,2},app.vars{2,2});
                        mathImage(isinf(mathImage)|isnan(mathImage)) = 0;
                        app.CallingApp.close_bandmath_app = 1; 
                    elseif app.varcnt == 4
                        mathImage = app.bandMathFunction(app.vars{1,2},app.vars{2,2},app.vars{3,2});
                        mathImage(isinf(mathImage)|isnan(mathImage)) = 0;
                        app.CallingApp.close_bandmath_app = 1;
                    elseif app.varcnt == 5 
                        mathImage = app.bandMathFunction(app.vars{1,2},app.vars{2,2},app.vars{3,2},app.vars{4,2});
                        mathImage(isinf(mathImage)|isnan(mathImage)) = 0;
                        app.CallingApp.close_bandmath_app = 1;
                    end
                    
                end
                
                if app.CallingApp.close_bandmath_app == 1
                    mathImage = (mathImage - min(mathImage(:)))/(max(mathImage(:))- min(mathImage(:)));
                                            
                    app.CallingApp.dataStruct(1).data = mathImage;
                    app.CallingApp.dataStruct(1).bands = 'bandmathImage';
                        
                    imshow(mathImage ,'parent',app.CallingApp.UIAxes);    
                    app.CallingApp.RedTextArea.Value = app.CallingApp.dataStruct(1).bands;
                                            
                    delete(app.UIFigure); %close the app
                end
            catch
                msgbox('Error - potentially an incorrect equation. Please redo.','error')
                delete(app.UIFigure); %close the app
            end
           
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            
            value = app.DropDown.Value;
            list = app.DropDown.Items;
            idx_file = find(strcmp(value,list)) + 1;
            
            % get index of selected band
            value = app.ListBox.Value;
            list = app.ListBox.Items;
            idx_band = find(strcmp(value,list));
            
            if size(app.DropDown.Items,2) == (idx_file - 1)
                try
                    img = app.CallingApp.selected_bands_image;
                catch
                    img = app.dataStruct(1).data;
                    idx_file = 1;
                end
            else
                img = app.dataStruct(idx_file).data;
            end


            app.vars{app.varcnt,2} = img(:,:,idx_band);
            
            if app.varcnt > 1 && app.displayPressed == 0
                app.SelectedVarTextArea.Value{end+1} = [char(app.vars{app.varcnt,1}),': ',char(app.dataStruct(idx_file).bands(idx_band))];
                app.displayPressed = app.displayPressed + 1;
            elseif app.varcnt > 1 && app.displayPressed > 0
                app.SelectedVarTextArea.Value{end} = [char(app.vars{app.varcnt,1}),': ',char(app.dataStruct(idx_file).bands(idx_band))];
            else
                app.SelectedVarTextArea.Value = [char(app.vars{1,1}),': ',char(app.dataStruct(idx_file).bands(idx_band))];
            end
            
            app.ListBox.Value = {};
                
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Filename'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Filename';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Bands'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Bands';
            app.InfoTextArea = uitextarea(app.UIFigure);
            app.InfoTextArea.Editable = 'off';
            app.InfoTextArea.Value = {'Select file for variable '};
            app.NextDoneButton = uibutton(app.UIFigure, 'push');
            app.NextDoneButton.ButtonPushedFcn = createCallbackFcn(app, @NextDoneButtonPushed, true);
            app.NextDoneButton.Text = 'Next/Done';
            app.VarTextArea = uitextarea(app.UIFigure);
            app.VarTextArea.Editable = 'off';
            app.FormulaTextArea = uitextarea(app.UIFigure);
            app.FormulaTextArea.Editable = 'off';
            app.SelectedVarTextArea = uitextarea(app.UIFigure);
            app.SelectedVarTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BandMath(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end