classdef CubeOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Flatfield                matlab.ui.container.ButtonGroup
        flatfield_yes            matlab.ui.control.RadioButton
        flatfield_no             matlab.ui.control.RadioButton
        TextArea                 matlab.ui.control.TextArea
        Calibrate                matlab.ui.container.ButtonGroup
        calibrate_yes_auto       matlab.ui.control.RadioButton
        calibrate_no             matlab.ui.control.RadioButton
        calibrate_yes_manual     matlab.ui.control.RadioButton
        TextArea_3               matlab.ui.control.TextArea
        TextArea_4               matlab.ui.control.TextArea
        Crop                     matlab.ui.container.ButtonGroup
        crop_yes                 matlab.ui.control.RadioButton
        crop_no                  matlab.ui.control.RadioButton
        Rotate                   matlab.ui.container.ButtonGroup
        rotate_no                matlab.ui.control.RadioButton
        rotate_clock             matlab.ui.control.RadioButton
        rotate_anti              matlab.ui.control.RadioButton
        rotate_180               matlab.ui.control.RadioButton
        Image                    matlab.ui.control.Image
        Image_2                  matlab.ui.control.Image
        Image_3                  matlab.ui.control.Image
        TextArea_5               matlab.ui.control.TextArea
        ImagingTextArea          matlab.ui.control.TextArea
        DoneContinueButton       matlab.ui.control.Button
        FilenameTextAreaLabel    matlab.ui.control.Label
        FilenameTextArea         matlab.ui.control.TextArea
        WavelengthTextAreaLabel  matlab.ui.control.Label
        WavelengthTextArea       matlab.ui.control.TextArea
        TextArea_6               matlab.ui.control.TextArea
        truecolor                matlab.ui.container.ButtonGroup
        truecolor_yes            matlab.ui.control.RadioButton
        truecolor_no             matlab.ui.control.RadioButton
        TextArea_7               matlab.ui.control.TextArea
        CancelButton             matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CallingApp % Description
        files
        selections = struct;
        str = struct; 
        img
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SpectralAnalysisApp, filepath, filenames, img, BW_calibration_target)
            app.CallingApp = SpectralAnalysisApp;
            app.img = img;
%             num = uint8(size(filenames,2)/2);
%             filename = [filepath,filenames{num}];
%             img = double(imread(filename));
%             img = (img - min(img(:)))./(max(img(:))-min(img(:)));
%             
%             % try auto calibration
%             [BW_calibration_target] = auto_calibrate(img);
%             app.BW_calibration_target = BW_calibration_target
            if size(BW_calibration_target,1) > 1
                RGB = app.img;
                RGB(:,:,2) = app.img;
                RGB(:,:,3) = app.img;
                col = RGB(:,:,1);
                col(BW_calibration_target == 1) = 1;
                RGB(:,:,1) = col;
                for i = 2:3
                    col = RGB(:,:,i);
                    col(BW_calibration_target == 1) = 0;
                    RGB(:,:,i) = col;
                end
                app.img = RGB;
            end
            
            imshow(app.img,'parent',app.UIAxes);
            
            %app.img = img;
            app.FilenameTextArea.Value = filenames{1};
            wave = regexp(filenames{1},'\d*','Match');
            app.WavelengthTextArea.Value = wave(end);
            path = userpath;
            try
                app.selections = load([path,'\selections.mat']);
                app.selections.cancel = 'no';
                if strcmp(app.selections.flatfield,'No')
                    app.Flatfield.SelectedObject.Value = 0;
                end
                if strcmp(app.selections.calibrate,'No')
                    app.Calibrate.SelectedObject.Value = 0;
                end
                if strcmp(app.selections.crop,'No')
                    app.Crop.SelectedObject.Value = 0;
                end 
            catch
                app.str = struct;
            end
            
            
        end

        % Button pushed function: DoneContinueButton
        function DoneContinueButtonPushed(app, event)
            
            app.selections.flatfield = app.Flatfield.SelectedObject.Text;
            app.selections.calibrate = app.Calibrate.SelectedObject.Text;
            app.selections.rotate = app.Rotate.SelectedObject.Text;
            app.selections.crop = app.Crop.SelectedObject.Text;
            app.selections.bandname = app.WavelengthTextArea.Value;
            app.selections.truecolor = app.truecolor.SelectedObject.Text;
            app.selections.cancel = 'no';
            
            
            str = app.selections;
            path = userpath;
            save([path,'\selections.mat'], '-struct', 'str');
            delete(app.UIFigure); %close the app
            
        end

        % Selection changed function: Rotate
        function RotateSelectionChanged(app, event)
            selectedButton = app.Rotate.SelectedObject.Text;
            
            if strcmp(selectedButton,'+90') 
               degrees = 90;
            elseif strcmp(selectedButton,'-90')
               degrees = -90;
            elseif strcmp(selectedButton,'180')
               degrees = 180;
            else
                degrees = 0;   
            end
            app.Rotate.SelectedObject.Text = num2str(degrees);
            
            img = app.img;
            temp = imrotate(img,degrees);
            imshow(temp,'parent',app.UIAxes);
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.selections.cancel = 'yes';

            str = app.selections;
            path = userpath;
            save([path,'\selections.mat'], '-struct', 'str');
            delete(app.UIFigure); %close the app
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Flatfield = uibuttongroup(app.UIFigure);
            app.Flatfield.Title = 'Flatfield?';
            app.flatfield_yes = uiradiobutton(app.Flatfield);
            app.flatfield_yes.Text = 'Yes';
            app.flatfield_yes.Value = true;
            app.flatfield_no = uiradiobutton(app.Flatfield);
            app.flatfield_no.Text = 'No';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Did you capture uniform white images in all wavelenghts? This is used to adjust the data for non-uniform illumination and camera fall-off.'};
            app.Calibrate = uibuttongroup(app.UIFigure);
            app.Calibrate.Title = 'Calibrate?';
            app.calibrate_yes_auto = uiradiobutton(app.Calibrate);
            app.calibrate_yes_auto.Text = 'Yes - auto';
            app.calibrate_yes_auto.Value = true;
            app.calibrate_no = uiradiobutton(app.Calibrate);
            app.calibrate_no.Text = 'No';
            app.calibrate_yes_manual = uiradiobutton(app.Calibrate);
            app.calibrate_yes_manual.Text = 'Yes - manual';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Would you like to calibrate your images? If so, there must be a calibration target in your scene (e.g. spectralon). '; 'If calibration target selected in Red in the displayed image is correct - select "auto".'};
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Depending on the image captured, you might want to crop the final output to inlcude only the document imaged. This helps to reduce image size'};
            app.Crop = uibuttongroup(app.UIFigure);
            app.Crop.Title = 'Crop?';
            app.crop_yes = uiradiobutton(app.Crop);
            app.crop_yes.Text = 'Yes';
            app.crop_yes.Value = true;
            app.crop_no = uiradiobutton(app.Crop);
            app.crop_no.Text = 'No';
            app.Rotate = uibuttongroup(app.UIFigure);
            app.Rotate.SelectionChangedFcn = createCallbackFcn(app, @RotateSelectionChanged, true);
            app.Rotate.Title = 'Rotate?';
            app.rotate_no = uiradiobutton(app.Rotate);
            app.rotate_no.Text = 'No';
            app.rotate_no.Value = true;
            app.rotate_clock = uiradiobutton(app.Rotate);
            app.rotate_clock.Text = '+90';
            app.rotate_anti = uiradiobutton(app.Rotate);
            app.rotate_anti.Text = '-90';
            app.rotate_180 = uiradiobutton(app.Rotate);
            app.rotate_180.Text = '180';
            app.Image = uiimage(app.Rotate);
            app.Image.ImageSource = 'rotate right.PNG';
            app.Image_2 = uiimage(app.Rotate);
            app.Image_2.ImageSource = 'rotate left.PNG';
            app.Image_3 = uiimage(app.Rotate);
            app.Image_3.ImageSource = 'rotate 180.PNG';
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'Before saving, do you want to rotate the image so that it opens upright next time?'};
            app.ImagingTextArea = uitextarea(app.UIFigure);
            app.ImagingTextArea.Editable = 'off';
            app.ImagingTextArea.Value = {'Cube building options'};
            app.DoneContinueButton = uibutton(app.UIFigure, 'push');
            app.DoneContinueButton.ButtonPushedFcn = createCallbackFcn(app, @DoneContinueButtonPushed, true);
            app.DoneContinueButton.Text = 'Done / Continue';
            app.FilenameTextAreaLabel = uilabel(app.UIFigure);
            app.FilenameTextAreaLabel.Text = 'Filename';
            app.FilenameTextArea = uitextarea(app.UIFigure);
            app.FilenameTextArea.Editable = 'off';
            app.WavelengthTextAreaLabel = uilabel(app.UIFigure);
            app.WavelengthTextAreaLabel.Text = 'Wavelength';
            app.WavelengthTextArea = uitextarea(app.UIFigure);
            app.WavelengthTextArea.Tooltip = {'Enter the wavelength of the displayed filename. Note, it has to be part of the filename to work. Thus if the filename is "Cary_Palimpsest_365_nm.TIF" you could enter "365". If you leave it blank, the wavelengths will not be added, and bands will be known only as band 1, band 2, etc...'};
            app.TextArea_6 = uitextarea(app.UIFigure);
            app.TextArea_6.Editable = 'off';
            app.TextArea_6.Value = {'Enter the wavelength displayed in the filename above '};
            app.truecolor = uibuttongroup(app.UIFigure);
            app.truecolor.Title = 'True color?';
            app.truecolor_yes = uiradiobutton(app.truecolor);
            app.truecolor_yes.Text = 'Yes';
            app.truecolor_yes.Value = true;
            app.truecolor_no = uiradiobutton(app.truecolor);
            app.truecolor_no.Text = 'No';
            app.TextArea_7 = uitextarea(app.UIFigure);
            app.TextArea_7.Editable = 'off';
            app.TextArea_7.Value = {'A true color image will be created from the MSI data using the captured wavelengths, D65 daylight data, and 2 degree observer data. Note, this for this to work the images needs to be calibrated.'};
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CubeOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end