classdef CurtainView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        LeftPanel    matlab.ui.container.Panel
        Panel        matlab.ui.container.Panel
        GridLayout2  matlab.ui.container.GridLayout
        ListBox      matlab.ui.control.ListBox
        DropDown     matlab.ui.control.DropDown
        LeftTop      matlab.ui.control.TextArea
        RightTop     matlab.ui.control.TextArea
        LeftBottom   matlab.ui.control.TextArea
        RightBottom  matlab.ui.control.TextArea
        RightPanel   matlab.ui.container.Panel
        UIAxes       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        CallingApp2 % Description
    end
    
    properties (Access = public)
        dataStruct % Description
        imageCube = []; % n x m x 4 matrix for display
        selectNum = 1;  % number of images selected - must be 4 before display happens
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.CallingApp2 = MainApp;
            app.DropDown.Items = MainApp.DropDown.Items;
            app.DropDown.Value = MainApp.DropDown.Value;
            app.ListBox.Items = MainApp.ListBox.Items;
            app.dataStruct = MainApp.dataStruct;
            
            if ~strcmp(app.DropDown.Items{end},'Current displayed image')
                app.DropDown.Items{end+1} = 'Current displayed image';
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            % get index of selected file
            value = app.DropDown.Value;
            list = app.DropDown.Items;
            idx_file = find(strcmp(value,list));
            try
                app.ListBox.Items = app.dataStruct(idx_file+1).bands; 
            catch
                app.ListBox.Items = app.dataStruct(1).bands;
            end
            app.ListBox.Value = {};
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.DropDown.Value;
            list = app.DropDown.Items;
            idx_file = find(strcmp(value,list)) + 1;
            
            % get index of selected band
            value = app.ListBox.Value;
            list = app.ListBox.Items;
            idx_band = find(strcmp(value,list));
            
            spacing = 2;
            
            if size(app.DropDown.Items,2) == (idx_file - 1)
                try
                    img = app.CallingApp.selected_bands_image;
                catch
                    img = app.dataStruct(1).data;
                    if size(img,3) > 1
                        img = img(:,:,idx_band);
                    end
                    idx_file = 1;
                end
            else
                img = app.dataStruct(idx_file).data(:,:,idx_band);
            end
            
            if app.selectNum == 1
                app.imageCube = zeros([size(img,1), size(img,2),4]);
                app.imageCube(:,:,1) = img;
                app.selectNum  = app.selectNum + 1;  
                app.LeftTop.Value = app.dataStruct(idx_file).bands(idx_band);
                app.RightTop.Value = 'Right top image';
                app.LeftBottom.Value = 'Left bottom image';
                app.RightBottom.Value = 'Right bottom image';
            else
                app.imageCube(:,:,app.selectNum) = img;  
                if app.selectNum == 2
                    app.RightTop.Value = app.dataStruct(idx_file).bands(idx_band);
                elseif app.selectNum == 3
                    app.LeftBottom.Value = app.dataStruct(idx_file).bands(idx_band);
                else
                    app.RightBottom.Value = app.dataStruct(idx_file).bands(idx_band);
                end
                
                app.selectNum  = app.selectNum + 1; 
            end
            
            if app.selectNum == 5
                app.selectNum  = 1; 
                row = uint16(size(img,1)/2);
                col = uint16(size(img,2)/2);
                
                dispImage = zeros([size(img,1), size(img,2)]);
                dispImage(1:row,1:col) = app.imageCube(1:row,1:col,1);      % left top
                dispImage(row:end,1:col) = app.imageCube(row:end,1:col,2);  % right top
                dispImage(1:row,col:end) = app.imageCube(1:row,col:end,3);  % left bottom
                dispImage(row:end,col:end) = app.imageCube(row:end,col:end,4);  % right bottom
                
                
                dispImage(row-spacing:row+spacing,1:end) = 0;
                dispImage(1:end,col-spacing:col+spacing) = 0;
                imshow(dispImage ,'parent',app.UIAxes); 
                
                dropdown_val = app.DropDown.Value;
                listbox_val = app.ListBox.Value;
                
                               
                while true
                    drawnow
                                        
                    if ~strcmp(dropdown_val,app.DropDown.Value) | ~strcmp(listbox_val,app.ListBox.Value)
                     break;
                    end
                    try
                        temp = app.UIFigure.Position
                        h = drawpoint('parent',app.UIAxes);
                        col = uint16(h.Position(1));
                        row = uint16(h.Position(2));
                        dispImage = zeros([size(img,1), size(img,2)]);
                        dispImage(1:row,1:col) = app.imageCube(1:row,1:col,1);      % left top
                        dispImage(row:end,1:col) = app.imageCube(row:end,1:col,2);  % right top
                        dispImage(1:row,col:end) = app.imageCube(1:row,col:end,3);  % left bottom
                        dispImage(row:end,col:end) = app.imageCube(row:end,col:end,4);  % right bottom
                        dispImage(row-spacing:row+spacing,1:end) = 0;
                        dispImage(1:end,col-spacing:col+spacing) = 0;
                        imshow(dispImage ,'parent',app.UIAxes); 
                    catch
                        break;
                    end
                    
                end
                
                
            end
            
            app.ListBox.Value = {};
        end

        % Button down function: UIAxes
        function ImageClickCallback(app, event)
            h = drawpoint('parent',app.UIAxes);
            row = uint16(h.Position(1));
            col = uint16(h.Position(2));
            dispImage = zeros([size(img,1), size(img,2)]);
            dispImage(1:row,1:col) = app.imageCube(1:row,1:col,1);      % left top
            dispImage(row:end,1:col) = app.imageCube(row:end,1:col,2);  % right top
            dispImage(1:row,col:end) = app.imageCube(1:row,col:end,3);  % left bottom
            dispImage(row:end,col:end) = app.imageCube(row:end,col:end,4);  % right bottom
            dispImage(row-10:row+10,1:end) = 1;
            dispImage(1:end,col-10:col+10) = 1;
            imshow(dispImage ,'parent',app.UIAxes); 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {491, 491};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {219, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.ListBox = uilistbox(app.GridLayout2);
            app.ListBox.Items = {'Select band'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Select band';
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {'Filename'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Filename';
            app.LeftTop = uitextarea(app.GridLayout2);
            app.LeftTop.Editable = 'off';
            app.LeftTop.Value = {'Left top image'};
            app.RightTop = uitextarea(app.GridLayout2);
            app.RightTop.Editable = 'off';
            app.RightTop.Value = {'Right top image'};
            app.LeftBottom = uitextarea(app.GridLayout2);
            app.LeftBottom.Editable = 'off';
            app.LeftBottom.Value = {'Left bottom image'};
            app.RightBottom = uitextarea(app.GridLayout2);
            app.RightBottom.Value = {'Right bottom image'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @ImageClickCallback, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CurtainView(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end