classdef SpectralAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Panel                        matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        DisplayResetButton           matlab.ui.control.Button
        BlueTextArea                 matlab.ui.control.TextArea
        GreenTextArea                matlab.ui.control.TextArea
        RedTextArea                  matlab.ui.control.TextArea
        RGBSwitch                    matlab.ui.control.Switch
        ListBox                      matlab.ui.control.ListBox
        DropDown                     matlab.ui.control.DropDown
        HueSliderLabel               matlab.ui.control.Label
        HueSlider                    matlab.ui.control.Slider
        SaturationSliderLabel        matlab.ui.control.Label
        SaturationSlider             matlab.ui.control.Slider
        BrightnessSliderLabel        matlab.ui.control.Label
        BrightnessSlider             matlab.ui.control.Slider
        NextButton                   matlab.ui.control.Button
        DisplayThumbnailButton       matlab.ui.control.Button
        PrevButton                   matlab.ui.control.Button
        UndockButton                 matlab.ui.control.Button
        ThresholdSliderLabel         matlab.ui.control.Label
        ThresholdSlider              matlab.ui.control.Slider
        InformationBox               matlab.ui.control.TextArea
        UIAxes2                      matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        FileMenu                     matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        BuildCubeMenu                matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        ENVIMenu                     matlab.ui.container.Menu
        JPGMenu                      matlab.ui.container.Menu
        PNGMenu                      matlab.ui.container.Menu
        TIFMenu                      matlab.ui.container.Menu
        CloseallMenu                 matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        EditMenu                     matlab.ui.container.Menu
        EnhanceMenu                  matlab.ui.container.Menu
        GaussianMenu                 matlab.ui.container.Menu
        HistogramequalizationMenu    matlab.ui.container.Menu
        SharpenMenu                  matlab.ui.container.Menu
        SmoothBlurMenu               matlab.ui.container.Menu
        RotateMenu                   matlab.ui.container.Menu
        InvertMenu                   matlab.ui.container.Menu
        CropMenu                     matlab.ui.container.Menu
        LinearStretchMenu            matlab.ui.container.Menu
        AnalysisMenu                 matlab.ui.container.Menu
        PrincipalComponentAnalysisPCAMenu  matlab.ui.container.Menu
        MaximumNoiseFractionMNFMenu  matlab.ui.container.Menu
        SpectralAngleMapperSAMMenu   matlab.ui.container.Menu
        BlurandDivideMenu            matlab.ui.container.Menu
        CurtainViewMenu              matlab.ui.container.Menu
        ForTheBrave                  matlab.ui.container.Menu
        ImageMath                    matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        listbox_selection = [];         % selected files and bands to display (1x2 or 3x2 matrix: first column = file #, second column = band #, Gray of RGB)
        open_files_dropdown_list = {};  % names of all open files in dropdown
        current_bands_listbox = {};     % bands displayed in listbox
        idx_RGB = [];                   % index of selected RGB bands
        filepath = [];
        selected_bands_image = [];      % selected bands image - while working on sliders and not pressing display again (display resets this)
        PCA_counter = 1;                % number of times PCA is created - for new name
        MNF_counter = 1;                % number of times MNF is created - for new name
        crop_counter = 1;               % number of times cropping is created - for new name
        listbox_value_temp = {};        % temporary holder for listbox values for next button
        nextButtonOrder = 1;            % order for RGB permute colors
        BandMathData                    % BandMath app data
        CubeOptionsData                 % Cube Options data
        thumbnail_image = []            % image stored in thumbnail
        Sam = 0                         % show if Sam has been run


    end
    
    properties (Access = public)
        dataStruct = struct();          % Sturct to keep image data  % Description
        close_bandmath_app = 0;         % To know when bandmath app is closed
        selections                      % cube building selections
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.ThresholdSlider.Enable = 'off';
            app.UIFigure.Icon = 'splash.PNG';
            app.UIFigure.Name = 'Spectral Image Analysis Environment';
            try
                user_name= getenv('username');
                app.filepath = ['C:\Users\',user_name,'\Desktop\'];
            catch
                app.filepath = ''
            end
            drawnow(); pause(.05)                       % <--------- add
            app.UIFigure.WindowState='maximized';       % <--------- add
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)

            app.HueSlider.Value = 50;
            app.SaturationSlider.Value = 50;
            app.BrightnessSlider.Value = 50;
            
            if  strcmp('Filename',app.DropDown(1).Value)
                open_files_dropdown_list = {};
            else
                open_files_dropdown_list = app.DropDown.Items;
            end
            
            dataStruct = app.dataStruct;
            cnt = length(dataStruct);
            idx_RGB = [];
            %current_bands_listbox = app.current_bands_listbox;

            
            [filename, filepath] = uigetfile([app.filepath,'\*.*']);
            drawnow;
            figure(app.UIFigure)
            
            if filename~=0
                set(app.UIFigure, 'pointer', 'watch')
                drawnow;
                
                datafile = [filepath, filename];
                if datafile(end-3:end) == '.img' | datafile(end-3:end) == '.hdr'
                    hdrfile = [filepath, filename(1:end-4),'.hdr'];
                    datafile = [filepath, filename(1:end-4)];
                else
                    hdrfile = [filepath, filename,'.hdr'];
                    datafile = [filepath, filename];
                end
            
                bands = {};
            
                try
                    [dataCube, info] = enviread(datafile,hdrfile);
                    dataCube = double(dataCube);
                    
                    for i = 1:size(dataCube,3)
                        data = double(dataCube(:,:,i));
                        dataCube(:,:,i) = (data - min(data(:)))/(max(data(:)) - min(data(:)));
                    end
                    
                    try
                        band = info.wavelength;
                    catch
                        band = info.band_names;
                    end
                    
                    try
                        wavelengths = info.wavelength;
                    catch
                        wavelengths = [];
                    end
                    
                    try
                        comma = strfind(band,',');
                        bands{1} = band(2:comma(1)-1);
            
                        for i = 2:size(comma,2)        
                            bands{i} = band(comma(i-1)+1:comma(i)-1);      
                        end
                            bands{i+1} = band(comma(i)+1:end-1);  
                    catch
                        for i = 1:size(dataCube,3)        
                            bands{i} = num2str(i);       
                        end 
                    end
                catch
                    try
                        dataCube = imread(datafile);
                        dataCube = double(dataCube);
                        for i = 1:size(dataCube,3) 
                            data = dataCube(:,:,i);
                            dataCube(:,:,i) = (data - min(data(:)))/(max(data(:)) - min(data(:)));
                            bands{i} = ['band ', num2str(i), ' (',filename(1:end-4),')']; 
                            
                        end
                    catch
                       message = sprintf('Image format not recognized.');
                       app.InformationBox.Value = ['Image format not recognized.']; 
                    end
                end
                set(app.UIFigure, 'pointer', 'arrow');
                            
            end
            
            try
                
                % if RGB image - display all three bands
                if length(bands) == 3
                    listbox_selection(1,1) = cnt+1;
                    listbox_selection(1,2) = 1;
                    listbox_selection(2,1) = cnt+1;
                    listbox_selection(2,2) = 2;
                    listbox_selection(3,1) = cnt+1;
                    listbox_selection(3,2) = 3;
                    current_image = dataCube(:,:,1);
                    current_image(:,:,2) = dataCube(:,:,2);
                    current_image(:,:,3) = dataCube(:,:,3);
                    app.RGBSwitch.Value = 'RGB';
                    idx_RGB = [1,2,3];
                    app.RedTextArea.Value = bands{1};
                    app.GreenTextArea.Value = bands{2};
                    app.BlueTextArea.Value = bands{3};

                else
                    mid = 1;
                    listbox_selection(1,1) = cnt+1;
                    listbox_selection(1,2) = mid;
                    current_image = dataCube(:,:,mid);
                    app.RGBSwitch.Value = 'Gray';
                    app.RedTextArea.Value = bands{1};
                    app.GreenTextArea.Value = 'Green';
                    app.BlueTextArea.Value = 'Blue';
                    
                end
                       
                dataStruct(1).name = 'currentImage';
                dataStruct(1).data = current_image;
                dataStruct(1).bands = bands(1);
                
                set(app.UIAxes,'visible','on');
                imshow(current_image,'parent',app.UIAxes);
             
                value = app.RGBSwitch.Value;
                    if strcmp(value,'RGB')
                        app.listbox_selection = zeros(3,2);
                        app.HueSlider.Enable = 'on';
                        app.SaturationSlider.Enable = 'on';
                        app.HueSliderLabel.Enable = 'on';
                        app.SaturationSliderLabel.Enable = 'on';
                        %app.PermuteRGBButton.Enable = 'on';
                        app.NextButton.Enable = 'on';
                    else
                        app.listbox_selection = zeros(1,2);
                        app.HueSlider.Enable = 'off';
                        app.SaturationSlider.Enable = 'off';
                        app.HueSliderLabel.Enable = 'off';
                        app.SaturationSliderLabel.Enable = 'off';
                        %app.PermuteRGBButton.Enable = 'off';
                        app.NextButton.Enable = 'on';
                    end
            
                if filename(end-3) == '.'
                    filename = filename(1:end-4)
                end
            
                open_files_dropdown_list{end+1} = filename; 
            
                cnt = length(dataStruct);
                idx_current = cnt+1;
                dataStruct(cnt+1).name = filename;
                dataStruct(cnt+1).data = dataCube;
                dataStruct(cnt+1).bands = bands;
                
                try
                    temp = regexp(wavelengths,'\d+\.?\d*','Match');
                    wave = zeros(size(dataCube,3),1);
                    for i = 1:size(dataCube,3) 
                        wave(i) = str2num(temp{i});
                    end
                    dataStruct(cnt+1).wavelengths = wave;
                    
                catch
                    dataStruct(cnt+1).wavelengths = [];
                end
                
                app.ListBox.Items = bands;
                app.listbox_value_temp = app.ListBox.Value;
                app.DropDown.Items = open_files_dropdown_list;
                app.DropDown.Value = filename;
                app.listbox_selection = listbox_selection;      
                app.dataStruct = dataStruct; 
                app.filepath = filepath;
                app.selected_bands_image = current_image;
                       
            catch
                ;
            end
        end

        % Value changed function: RGBSwitch
        function RGBSwitchValueChanged(app, event)
            value = app.RGBSwitch.Value;
            if strcmp(value,'RGB')
                app.listbox_selection = zeros(3,2);
                app.HueSlider.Enable = 'on';
                app.SaturationSlider.Enable = 'on';
                app.HueSliderLabel.Enable = 'on';
                app.SaturationSliderLabel.Enable = 'on';
                %app.PermuteRGBButton.Enable = 'on';
                %app.NextButton.Enable = 'off';
                
            else
                app.listbox_selection = zeros(1,2);
                app.HueSlider.Enable = 'off';
                app.SaturationSlider.Enable = 'off';
                app.HueSliderLabel.Enable = 'off';
                app.SaturationSliderLabel.Enable = 'off';
                %app.PermuteRGBButton.Enable = 'off';
                %app.NextButton.Enable = 'on';
            end
            
            app.RedTextArea.Value = 'Red';
            app.GreenTextArea.Value = 'Green';
            app.BlueTextArea.Value = 'Blue';
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            
            listbox_selection = app.listbox_selection;
            app.nextButtonOrder = 1;
            
            % get index of selected file - add one since first in
            % dataStruct is current_image
            value = app.DropDown.Value;
            list = app.DropDown.Items;
            idx_file = find(strcmp(value,list)) + 1;
            
            % get index of selected band
            value = app.ListBox.Value;
            list = app.ListBox.Items;
            idx_band = find(strcmp(value,list));
                
            RGBgray_selection = app.RGBSwitch.Value;
            
            if strcmp(RGBgray_selection,'Gray')
                
                listbox_selection = [];
                listbox_selection(1,1) = idx_file;
                listbox_selection(1,2) = idx_band;
                app.RedTextArea.Value = value;
            else
                 
                if size(listbox_selection,1) == 3
                    listbox_selection = [];
                    listbox_selection(1,1) = idx_file;
                    listbox_selection(1,2) = idx_band;
                    app.RedTextArea.Value = value;
                    app.GreenTextArea.Value = 'Green';
                    app.BlueTextArea.Value = 'Blue';
                else
                    val = size(listbox_selection,1);
                    listbox_selection(val+1,1) = idx_file;
                    listbox_selection(val+1,2) = idx_band;
                    if val == 1
                        app.GreenTextArea.Value = value;
                    else
                        app.BlueTextArea.Value = value;
                    end
                end
                
            end
                
            app.listbox_selection = listbox_selection;
            app.listbox_value_temp = app.ListBox.Value;
            app.ListBox.Value = {};
                     
        end

        % Value changed function: DropDown
        function DropDownOpening(app, event)
                  
            
            % get index of selected file
            value = app.DropDown.Value;
            list = app.DropDown.Items;
            idx_file = find(strcmp(value,list));
            
            app.ListBox.Items = app.dataStruct(idx_file+1).bands;


        end

        % Button pushed function: DisplayResetButton
        function DisplayResetButtonPushed(app, event)
            
            try
                app.dataStruct = get_selected_image_from_dataStruct(app.listbox_selection,app.dataStruct);
                
                current_image = app.dataStruct(1).data;
                
                imshow(current_image,'parent',app.UIAxes);
                
                if size(current_image,3) == 1
                    app.GreenTextArea.Value = 'Green';
                    app.BlueTextArea.Value = 'Blue';
                    app.RGBSwitch.Value = 'Gray';
                end
                
                app.selected_bands_image = current_image;
            catch
                uiwait(msgbox('Remember to select the bands to display, or if RGB, check that your images are the same size','OK','help'))
            end
            
            app.HueSlider.Value = 50;
            app.SaturationSlider.Value = 50;
            app.BrightnessSlider.Value = 50;
            app.ThresholdSlider.Value = 50;
            app.Sam = 0;
            %app.ThresholdSlider.Enable = 'off';
            set(app.UIFigure, 'pointer', 'arrow');
            app.InformationBox.Value = ['Displayed'];
            app.ListBox.Value = {};
            
        end

        % Menu selected function: PrincipalComponentAnalysisPCAMenu
        function PrincipalComponentAnalysisPCAMenuSelected(app, event)
            
            try
                cnt = length(app.dataStruct);
                
                [indx,~] = listdlg('PromptString',{'Select file for PCA'},...
                    'SelectionMode','single','ListString',app.DropDown.Items, 'ListSize',[250,100]);
    
                set(app.UIFigure, 'pointer', 'watch');
                drawnow;
                figure(app.UIFigure)
                
                dataCube = app.dataStruct(indx+1).data;
                
                [indx2,~] = listdlg('PromptString',{'Select bands to use for PCA. Hold shift / cntr to select multiple bands.'},...
                    'SelectionMode','multiple','ListString',app.dataStruct(indx+1).bands, 'ListSize',[160,100]);
                
                dataCube = dataCube(:,:,indx2);
                bands_to_keep = size(dataCube,3);
                
                [dataPCA, bandsPCA] = PCAcalc(dataCube,bands_to_keep);
                
                current_image = dataPCA(:,:,1);
                
                if app.PCA_counter == 1
                    app.dataStruct(cnt+1).name = ['PCA_',app.dataStruct(indx+1).name];
                    app.PCA_counter = app.PCA_counter + 1;
                else
                   app.dataStruct(cnt+1).name = ['PCA_',num2str(app.PCA_counter),'_',app.dataStruct(indx+1).name];
                   app.PCA_counter = app.PCA_counter + 1;
                end   
                   
                app.dataStruct(cnt+1).data = dataPCA;
                app.dataStruct(cnt+1).bands = bandsPCA;
                
                app.DropDown.Items{end+1} = app.dataStruct(cnt+1).name;
                app.ListBox.Items = bandsPCA;
                app.DropDown.Value = app.dataStruct(cnt+1).name;
                
                app.dataStruct(1).name = 'currentImage';
                app.dataStruct(1).data = current_image;
    
                set(app.UIAxes,'visible','on');
                imshow(current_image,'parent',app.UIAxes);
                
                app.RGBSwitch.Value = 'Gray';
                app.RedTextArea.Value = bandsPCA{1};
                app.GreenTextArea.Value = 'Green';
                app.BlueTextArea.Value = 'Blue';
                
                app.listbox_selection = [];
                app.listbox_selection(1,1) = cnt+1;
                app.listbox_selection(1,2) = 1;
                app.listbox_value_temp = bandsPCA{1};
                
                app.InformationBox.Value = ['PCA completed'];
                
                set(app.UIFigure, 'pointer', 'arrow');
            catch
                ;
            end
            
        end

        % Menu selected function: ENVIMenu
        function ENVIMenuSelected(app, event)
            
            if ~strcmp(app.DropDown.Items{end},'Current displayed image')
                app.DropDown.Items{end+1} = 'Current displayed image';
            end
            
            [indx,tf] = listdlg('PromptString',{'Select file to save'},...
                'SelectionMode','single','ListString',app.DropDown.Items,'ListSize',[160,160]);
            try 
                if tf == 1
                    if size(app.DropDown.Items,2) == indx
                        if isempty(app.selected_bands_image)
                            dataCube = app.dataStruct(1).data;
                        else
                            dataCube = app.selected_bands_image;
                        end
                        bands = app.dataStruct(1).bands;
                        object_name = app.dataStruct(1).name;
                    else
                        dataCube = app.dataStruct(indx+1).data;
                        bands = app.dataStruct(indx+1).bands;
                        object_name = app.dataStruct(indx+1).name;
                    end
            
                    info = struct;
                    info.description= '{Saved by Spectral Analysis App}';
                    info.samples= size(dataCube,2);
                    info.lines= size(dataCube,1);
                    info.bands= size(dataCube,3);
                    info.header_offset= 0;
                    info.file_type= 'ENVI Standard';
                    info.data_type= 4;
                    info.interleave= 'bsq';
                    info.sensor_type= 'Unknown';
                    info.byte_order= 0;
                    info.band_names= ['{',char(strjoin(bands,',')),'}'];
                    try
                        wavelengths = app.dataStruct(indx+1).wavelengths;
                        info.wavelength = ['{',char(strjoin(wavelengths,',')),'}'];
                    
                    catch
                        ;
                    end
            
                    savename = [object_name,'.img'];
                    [filename, foldername] = uiputfile([app.filepath,savename]);
                    if filename(end-3:end) == '.img'
                        filename = filename(1:end-4)
                    end
            
                    set(app.UIFigure, 'pointer', 'watch');
            
                    datafile = [foldername, filename];
                    hdrfile = [foldername, filename,'.hdr'];
            
                    enviwrite(dataCube,info,datafile,hdrfile);
            
                    set(app.UIFigure, 'pointer', 'arrow');
            
                    app.InformationBox.Value = ['Image saved.'];
                    app.DropDown.Items(end) = [];
                end
            catch
                app.InformationBox.Value = ['Error: Saving ENVI file'];
            end
            figure(app.UIFigure)
    
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            if ~strcmp(app.DropDown.Items{end},'Current displayed image')
                app.DropDown.Items{end+1} = 'Current displayed image';
            end
            
            [indx,tf] = listdlg('PromptString',{'Select file to save'},...
                'SelectionMode','single','ListString',app.DropDown.Items,'ListSize',[160,160]);
            
            if tf == 1
                if size(app.DropDown.Items,2) == indx
                    if isempty(app.selected_bands_image)
                        dataCube = app.dataStruct(1).data;
                    else
                        dataCube = app.selected_bands_image;
                    end
                else
                    dataCube = app.dataStruct(indx+1).data;
                end
                
                try 
                    if size(dataCube,3) > 3
                        uiwait(msgbox('Jpeg format cannot save images with more than 3 bands. Use ENVI format','Jpeg', 'help'));
                    elseif size(dataCube,3) == 3 || size(dataCube,3) == 1
                        try
                            savename = [app.dataStruct(indx+1).name,'.jpg'];
                        catch
                            savename = ['.jpg'];
                        end
                        [filename, foldername] = uiputfile([app.filepath, savename]);
                        imwrite(dataCube, [foldername,filename]);
                        app.InformationBox.Value = ['Image saved.'];
                        app.DropDown.Items(end) = [];
                    end
                
                catch
                    app.InformationBox.Value = ['Error: Saving JPG file'];
                end
            end
            figure(app.UIFigure)
            
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            if ~strcmp(app.DropDown.Items{end},'Current displayed image')
                app.DropDown.Items{end+1} = 'Current displayed image';
            end
            
            [indx,tf] = listdlg('PromptString',{'Select file to save'},...
                'SelectionMode','single','ListString',app.DropDown.Items,'ListSize',[160,160]);
            
            if tf == 1
                if size(app.DropDown.Items,2) == indx
                    if isempty(app.selected_bands_image)
                        dataCube = app.dataStruct(1).data;
                    else
                        dataCube = app.selected_bands_image;
                    end
                else
                    dataCube = app.dataStruct(indx+1).data;
                end
                
                try 
                    if size(dataCube,3) > 3
                        uiwait(msgbox('PNG format cannot save images with more than 3 bands. Use ENVI format','PNG', 'help'));
                    elseif size(dataCube,3) == 3 || size(dataCube,3) == 1
                        savename = ['.png'];
                        [filename, foldername] = uiputfile([app.filepath,savename]);
                        imwrite(dataCube, [foldername,filename]);
                        app.InformationBox.Value = ['Image saved.'];
                        app.DropDown.Items(end) = [];
                    end
                
                catch
                    app.InformationBox.Value = ['Error: Saving PNG file'];
                end
            end
            
            figure(app.UIFigure)
            
        end

        % Menu selected function: TIFMenu
        function TIFMenuSelected(app, event)
            if ~strcmp(app.DropDown.Items{end},'Current displayed image')
                app.DropDown.Items{end+1} = 'Current displayed image';
            end
            
            [indx,tf] = listdlg('PromptString',{'Select file to save'},...
                'SelectionMode','single','ListString',app.DropDown.Items,'ListSize',[160,160]);
            
            if tf == 1
                if size(app.DropDown.Items,2) == indx
                    if isempty(app.selected_bands_image)
                        dataCube = app.dataStruct(1).data;
                    else
                        dataCube = app.selected_bands_image;
                    end
                else
                    dataCube = app.dataStruct(indx+1).data;
                end
                
                try 
                    if size(dataCube,3) > 3
                        uiwait(msgbox('Tif format cannot save images with more than 3 bands. Use ENVI format','Tif', 'help'));
                    elseif size(dataCube,3) == 3 || size(dataCube,3) == 1
                        savename = ['.tif'];
                        [filename, foldername] = uiputfile([app.filepath,savename]);
                        imwrite(dataCube, [foldername,filename]);
                        app.InformationBox.Value = ['Image saved.'];
                        app.DropDown.Items(end) = [];
                    end
                
                catch
                    app.InformationBox.Value = ['Error: Saving TIF file'];
                end
            end
            figure(app.UIFigure)
        end

        % Menu selected function: BuildCubeMenu
        function BuildCubeMenuSelected(app, event)
          
            app.InformationBox.Value = ['Select all raw files'];
            
            [filenames, filepath] = uigetfile([app.filepath,'\*.*'],'Multiselect', 'on','Select all images captured')


            drawnow;
            figure(app.UIFigure)
            
            if filepath~=0
                
                if  strcmp('Filename',app.DropDown(1).Value)
                    open_files_dropdown_list = {};
                else
                    open_files_dropdown_list = app.DropDown.Items;
                end
                
                set(app.UIFigure, 'pointer', 'watch'); 
                cnt = length(app.dataStruct);
                
                num = uint8(size(filenames,2)/2);
                filename = [filepath,filenames{num}];
                img = double(imread(filename));
                img = (img - min(img(:)))./(max(img(:))-min(img(:)));
            
                % try auto calibration
                [BW_calibration_target] = auto_calibrate(img);
                
                
                app.InformationBox.Value = ['Complete Cube Building pop-up'];
                fig = CubeOptions(app, filepath, filenames, img, BW_calibration_target);
               
                while isvalid(fig) 
                    pause(0.2); 
                end
                
                pause(0.2);
                app.InformationBox.Value = ['Stacking images'];
                
                path = userpath;
                selection = load([path,'\selections.mat']);
                
                if strcmp(selection.cancel,'yes')
                    app.InformationBox.Value = ['Operation cancelled.'];
                    set(app.UIFigure, 'pointer', 'arrow');
                    close(gcf)
                    return
                end
                
                
                try
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    
                    app.InformationBox.Value = ['Building image cube'];
                    
                    num_files = numel(filenames);
                    bands = {};
                    dataCube = [];
                    wavelengths = {};
                    file_name = '';
                    
                    try % for windows
                        idcs   = strfind(filepath,'\');
                        newdir = [filepath(1:idcs(end-1)-1),'\'];
                    catch % for mac
                        idcs   = strfind(filepath,'/');
                        newdir = [filepath(1:idcs(end-1)-1),'/'];
                    end
                    
                    filename = filenames{1};
                    try
                        band_start = strfind(filename,selection.bandname);
                        band_length = size(char(selection.bandname),2);
                    catch
                        band_start = 1;
                        band_length = size(filename,2) - 4;
                    end
                    
                    %flatfield 
                    if strcmp(selection.flatfield,'Yes')
                        app.InformationBox.Value = ['Select all the flats'];
                        [white_filesnames, white_filepath] = uigetfile([newdir,'*.tif'],'Multiselect', 'on','Select flatfield images');
                        white_filenames = dir(white_filepath);    
                        file_name = [file_name, '_flatfield'];
                        
                    end
                    
                    % create cube
                    app.InformationBox.Value = ['Creating image cube'];
                    
                    for j = 1:num_files
                        filename = [filepath,filenames{j}];
                        img = imread(filename); 
                        
                        if strcmp(selection.flatfield,'Yes')
                               
                           white = imread([white_filepath,white_filenames(j+2).name]);
                           %smooth potential small non-uniformities on white flat
                           white = imgaussfilt(double(white),20);
                           img = double(img)./double(white);
                        end
                                
                        dataCube(:,:,j) = img;
                        
                        name_file = filenames(j);
                        wavelengths{j} = name_file{1}(band_start:band_start+band_length-1);
                        bands{j} = ['band ',num2str(j),': (',name_file{1}(band_start:band_start+band_length-1),')'];
                    end
                    
                    % rotate images
                    if strcmp(selection.rotate,'90')
                        app.InformationBox.Value = ['Rotating images'];
                        degrees = 90;
                        file_name = [file_name, '_rotated'];
                        BW_calibration_target = imrotate(BW_calibration_target,degrees);
                    elseif strcmp(selection.rotate,'-90')
                        app.InformationBox.Value = ['Rotating images'];
                        degrees = -90;
                        file_name = [file_name, '_rotated'];
                        BW_calibration_target = imrotate(BW_calibration_target,degrees);
                    elseif strcmp(selection.rotate,'180') 
                        app.InformationBox.Value = ['Rotating images'];
                        degrees = 180;
                        file_name = [file_name, '_rotated'];
                        BW_calibration_target = imrotate(BW_calibration_target,degrees);
                    else 
                       degrees = 0;
                    end 
                        
                    temp = dataCube;
                    dataCube = imrotate(temp(:,:,1),degrees);
                    for i = 2:size(temp,3)
                        dataCube(:,:,i) = imrotate(temp(:,:,i),degrees);
                    end
                    
                    
                    % calibrate images 
                    
                    if strcmp(selection.calibrate,'Yes - manual')
                        app.InformationBox.Value = ['Calibrating Images'];
                        cal = 1;
                        file_name = [file_name, '_calibrated'];
                    elseif strcmp(selection.calibrate,'Yes - auto')
                        app.InformationBox.Value = ['Calibrating Images'];
                        cal = 0;
                        file_name = [file_name, '_calibrated'];
                    else 
                        cal = 3;
                    %             case 'Yes - Polygon'
                    %                 cal = 2;
                    
                    end
                    
                    % calibrate image
                    if cal < 3
                            
                        while cal ~= 3 && cal ~= 0
                            % stretch image before displaying
                            figure
                            temp = dataCube(:,:,round(num_files/2));
                            [temp]=stretchImage(temp,1.5);
                            imshow(temp)
                            app.InformationBox.Value = ['Select calibration target'];
                            title('Drag mouse inside calibration target (rectangle). Note: dont include data outside target', 'FontSize', 8);
                        
                            if cal == 1
                                hFH = imrect();
                                binaryImage = hFH.createMask();
                                close(gcf); 
                            end
                            
                            if cal == 2 
                                hFH = imfreehand();
                                binaryImage = hFH.createMask();
                                close(gcf);
                            end
                            
                            answer = questdlg('Since people often mess this up, I have to ask... Do you want to redo the calibration selection?', ...
                            '', 'Yes - Rectangle' ,'Yes - Polygon', 'No/Done','No/Done' );
                    
                            switch answer
                                case 'Yes - Rectangle'
                                    cal = 1;
                                case 'Yes - Polygon'
                                    cal = 2;
                                case 'No/Done'
                                    cal = 3;
                                case ''
                                    return
                            end
                        end
                        
                        
                        if cal == 0
                           binaryImage = BW_calibration_target;
                        end
                        
                        app.InformationBox.Value = ['Calibrating images']; 
                        binaryImage_cube = repmat(binaryImage,[1,1,num_files]);
                    
                        temps = double(dataCube).*double(binaryImage_cube);
                        cnt2 = sum(sum(binaryImage(binaryImage ~= 0)));
                        meantemp = sum(sum(temps))/cnt2;
                        meantemp_cube = repmat(meantemp,[size(dataCube,1) size(dataCube,2),1]);
                        %calibrate data - divide by mean spectralon per band
                        dataCube = double(dataCube)./meantemp_cube;  
                    
                        dataCube(dataCube > 1) = 1;
                        
                        
                        %min_val = min(min(dataCube));
                        %min_val = repmat(min_val,[size(dataCube,1) size(dataCube,2),1]);
                        %dataCube = (dataCube - min_val)./(1 - min_val);
                            
                    
                    else
                       
                        dataCube=stretchImage(dataCube,2);
                    
                    end
                        
                    % crop
                    if strcmp(selection.crop,'Yes')
                        roi = 1;
                        file_name = [file_name, '_cropped'];
                    else
                        roi = 0;
                    end 
                        
                    while roi == 1
                     
                       figure
                       temp = dataCube(:,:,round(num_files/2));
                       [temp]=stretchImage(temp, 1.5);
                       imshow(temp,[])
                       
                       title('Crop image (rectangle). Hold and drag mouse', 'FontSize', 8)
                       app.InformationBox.Value = ['Crop image (rectangle)'];
                    
                       hFH = imrect();
                       xy = hFH.getPosition;
                       close(gcf)
                       
                       app.InformationBox.Value = ['Cropping images'];
                    
                       xy = floor(xy);
                    
                       row_end = xy(2)+xy(4);
                       col_end = xy(1)+xy(3);
                    
                       if row_end > size(temp,1)
                           row_end = size(temp,1);
                       end
                       if col_end > size(temp,2)
                           col_end = size(temp,2);
                       end
                    
                       dataCube_temp = dataCube(xy(2):row_end,xy(1):col_end,:);
                    
                       temp = dataCube_temp(:,:,round(num_files/2));
                       [temp]=stretchImage(temp, 1.5);
                       imshow(temp,[]) 
                    
                       answer = questdlg('Would you like to redo the ROI?', ...
                        'Select ROI?', 'Yes','No','No');
                       app.InformationBox.Value = ['Redo ROI pop-up'];
                    
                            switch answer
                                case 'Yes'
                                    roi = 1;
                                    app.InformationBox.Value = ['Crop image'];
                                case 'No'
                                    roi = 2;
                                    dataCube = dataCube_temp;
                                    close(gcf)
                                    app.InformationBox.Value = ['Image cropped'];
                                case ''
                                    return
                            end 
                    
                                      
                    end  
                    
                    if strcmp(selection.truecolor,'Yes')
                        app.InformationBox.Value = ['Creating true color images'];
                        [RGB, bands_RGB] = true_color(dataCube,str2num(char(wavelengths)));
                        imshow(RGB,'parent',app.UIAxes2);
                        app.thumbnail_image = RGB;
                    end
                    
                    
                    close(gcf)
                    
                    % save raw data cube
                    info = struct;
                    info.description= '{Saved by Spectral Analysis App}';
                    info.samples= size(dataCube,2);
                    info.lines= size(dataCube,1);
                    info.bands= size(dataCube,3);
                    info.header_offset= 0;
                    info.file_type= 'ENVI Standard';
                    info.data_type= 4;
                    info.interleave= 'bsq';
                    info.sensor_type= 'Unknown';
                    info.byte_order= 0;
                    info.band_names= ['{',char(strjoin(bands,',')),'}'];
                    try
                        %wavelengths = app.dataStruct(indx+1).wavelengths;
                        info.wavelength = ['{',char(strjoin(wavelengths,',')),'}'];
                    
                    catch
                        ;
                    end


                    try
                        idcs   = strfind(filepath,'/');
                        newdir = filepath(1:idcs(end-1));
                        dir_names = dir(newdir);
                        if ~strcmp(dir(newdir),'processed')
                            mkdir([newdir,'/processed']);
                            newdir_processed = [newdir,'/processed/'];
                        end
                    catch
                        idcs   = strfind(filepath,'\');
                        newdir = filepath(1:idcs(end-1));
                        dir_names = dir(newdir);
                        if ~strcmp(dir(newdir),'processed')
                            mkdir([newdir,'\processed']);
                            newdir_processed = [newdir,'\processed\'];
                        end
                    end


                    object_name = filepath(idcs(end-2)+1:idcs(end-1)-1);
                    savename = [newdir_processed,object_name,file_name,'_dataCube','.img'];
                    app.InformationBox.Value = ['Select destination to save file'];
                    
                    [filename, foldername] = uiputfile(savename, 'Save datacube');
                    if filename(end-3:end) == '.img'
                        filename = filename(1:end-4);
                    end
                    
                    app.InformationBox.Value = ['Saving file'];
                    
                    datafile = [foldername, filename];
                    hdrfile = [foldername, filename,'.hdr'];
                    
                    enviwrite(dataCube,info,datafile,hdrfile);
                    
                    savenameRGB = [newdir_processed,object_name,'_RGB','.tif'];
                    imwrite(RGB, savenameRGB);
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
                    %[dataCube,bands, wavelengths, filepath, object_name] = ImportBandsAndCalibrate_app(filepath,filenames, selection);
                    set(app.current_bands_listbox,'String',bands);
                    app.InformationBox.Value = ['Image cube saved'];
                catch
                    app.InformationBox.Value = ['Operation cancelled / aborted.'];
                    
                    set(app.UIFigure, 'pointer', 'arrow')
                    return
                end
                
                try
                 
                    current_image = dataCube(:,:,1);
                    app.listbox_selection = [];
                    app.listbox_selection(1,1) = cnt+1;
                    app.listbox_selection(1,2) = 1;
                    app.dataStruct(1).name = 'currentImage';
                    app.dataStruct(1).data = current_image;
                    app.listbox_value_temp = bands{1};
                    
                    %pathparts = strsplit(filepath,filesep);
                    
                    
                    app.dataStruct(cnt+1).name = [object_name,'_dataCube'];
                    app.dataStruct(cnt+1).data = dataCube;
                    app.dataStruct(cnt+1).bands = bands;
                    app.dataStruct(cnt+1).wavelengths = wavelengths;
                    
                    open_files_dropdown_list{end+1} = app.dataStruct(cnt+1).name;
                    app.DropDown.Items = open_files_dropdown_list;
                    app.ListBox.Items = bands;
                    app.DropDown.Value = app.dataStruct(cnt+1).name;
            
                    app.dataStruct(1).name = 'currentImage';
                    app.dataStruct(1).data = current_image;
                    app.selected_bands_image = current_image;

                    set(app.UIAxes,'visible','on');
                    imshow(current_image,'parent',app.UIAxes);
            
                    app.RGBSwitch.Value = 'Gray';
                    app.RedTextArea.Value = bands{1};
                    app.GreenTextArea.Value = 'Green';
                    app.BlueTextArea.Value = 'Blue';
                    app.filepath = filepath;
                    
                    value = app.RGBSwitch.Value;
                    if strcmp(value,'RGB')
                        app.listbox_selection = zeros(3,2);
                        app.HueSlider.Enable = 'on';
                        app.SaturationSlider.Enable = 'on';
                        app.HueSliderLabel.Enable = 'on';
                        app.SaturationSliderLabel.Enable = 'on';
                        %app.PermuteRGBButton.Enable = 'on';
                        %app.NextButton.Enable = 'off';
                    else
                        app.listbox_selection = zeros(1,2);
                        app.HueSlider.Enable = 'off';
                        app.SaturationSlider.Enable = 'off';
                        app.HueSliderLabel.Enable = 'off';
                        app.SaturationSliderLabel.Enable = 'off';
                        %app.PermuteRGBButton.Enable = 'off';
                        app.NextButton.Enable = 'on';
                        %app.listbox_value_temp = app.ListBox.Value;
                    end
                            
                    app.InformationBox.Value = ['Image saved.'];
                    set(app.UIFigure, 'pointer', 'arrow');
                   
                    
                catch
                    app.InformationBox.Value = ['Operation cancelled.'];
                    set(app.UIFigure, 'pointer', 'arrow');
                    close(gcf)
                    return
                end
            end
        end

        % Callback function
        function PermuteRGBButtonPushed(app, event)
            
%             filepath = app.filepath;
%             current_image = app.dataStruct(1).data; 
%             bands = app.dataStruct(1).bands;
%             
%             try
%                 DisplayRGB(current_image,bands,filepath);
%             catch
%                 message = sprintf('Select RGB image to display - option not work with grayscale image.');
%                 uiwait(msgbox(message));
%             end
        end

        % Menu selected function: InvertMenu
        function InvertMenuSelected(app, event)
            
            
            current_image = app.dataStruct(1).data;
            band = app.dataStruct(1).bands;

            current_image = 1-current_image;
            imshow(current_image,'parent',app.UIAxes);
            %impixelinfo;

            app.dataStruct(1).data = current_image;
            app.dataStruct(1).bands =  strcat(string(band),' Inverted image');
            app.RedTextArea.Value = app.dataStruct(1).bands(1);
            
            value = app.RGBSwitch.Value;
            if strcmp(value,'RGB')
                app.GreenTextArea.Value = app.dataStruct(1).bands(2);
                app.BlueTextArea.Value = app.dataStruct(1).bands(3);
            end
            app.BrightnessSlider.Value = 50;
            app.SaturationSlider.Value = 50;
            app.BrightnessSlider.Value = 50;

            app.selected_bands_image = current_image;

        end

        % Menu selected function: RotateMenu
        function RotateMenuSelected(app, event)
            
            answer = questdlg('Rotate clockwise', ...
	            'Rotate', '90 deg','180 deg','mirror','mirror');
            
            current_image = app.dataStruct(1).data;

            switch answer
                case '90 deg'
                    current_image = imrotate(current_image,-90);
                case '180 deg'
                    current_image = imrotate(current_image,180);
                case 'mirror'
                    for i = 1:size(current_image,3)
                        current_image(:,:,i) = flip(current_image(:,:,i));
                    end
            end

            app.dataStruct(1).data = current_image;
            app.selected_bands_image = current_image;
  
            imshow(current_image,'parent',app.UIAxes);
            %impixelinfo; 
            
            if size(current_image,3) == 3
                app.RedTextArea.Value = strcat(string(app.dataStruct(1).bands(1)),' Rotated image');
                app.GreenTextArea.Value = strcat(string(app.dataStruct(1).bands(2)),' Rotated image');
                app.BlueTextArea.Value = strcat(string(app.dataStruct(1).bands(2)),' Rotated image');
            else 
                app.RedTextArea.Value = strcat(string(app.dataStruct(1).bands),' Rotated image');
            end
        
        end

        % Callback function
        function ThresholdMaskMenuSelected(app, event)
                                   
            current_image = app.dataStruct(1).data; 
            bands = app.dataStruct(1).bands;
            
            if size(bands,1) > 1
                uiwait(msgbox('Can only threshold grayscale images.','Error'));
                return
            end
            
            Threshold(current_image,bands,app.filepath);
            
            
        end

        % Menu selected function: BlurandDivideMenu
        function BlurandDivideMenuSelected(app, event)
           
            try
                cnt = length(app.dataStruct);
                
                [indx,~] = listdlg('PromptString',{'Select file for PCA'},...
                    'SelectionMode','single','ListString',app.DropDown.Items, 'ListSize',[250,100]);
    
                set(app.UIFigure, 'pointer', 'watch');
                drawnow;
                figure(app.UIFigure)
                
                dataCube = app.dataStruct(indx+1).data;
%                 value = app.RGBSwitch.Value;
%     
%                 if strcmp(value,'RGB')
%                     uiwait(msgbox('Blur & Divide only works on grayscale image. Please display the grayscale image before selecting this option','Error'));
%                 else
                    % get blur size
                prompt = {'Enter kernel size:'};
                title = 'Input';
                dims = [1 35];
                definput = {'51'};
                answer = inputdlg(prompt,title,dims,definput);
                kernel = str2double(answer{1});
                
                set(app.UIFigure, 'pointer', 'watch');
                                
                % get grayscale image displayed
                %band = app.dataStruct(1).bands;
                %img = app.dataStruct(1).data;
                
                BandD_cube = zeros(size(dataCube));
                
                for i = 1:size(dataCube,3)
                    img = dataCube(:,:,i);
                    h = ones(kernel,kernel)/kernel^2; 
                    blur = imfilter(img,h);
                    imgOut = img./blur;
                    imgOut(imgOut > 1) = 1;
                    BandD_cube(:,:,i) = imgOut;
                end
                
                % display result
                set(app.UIAxes,'visible','on');
                imshow(BandD_cube(:,:,1),'parent',app.UIAxes);
                %impixelinfo;
                
                app.dataStruct(cnt+1).data = BandD_cube;
                app.dataStruct(cnt+1).bands = strcat(app.dataStruct(indx+1).bands,'_B&D');
                
                app.dataStruct(cnt+1).name = 'Blur&Divide';
                
                temp = dataCube./BandD_cube;
                app.dataStruct(cnt+2).data = temp;
                app.dataStruct(cnt+2).bands = strcat(app.dataStruct(indx+2).bands,'Cube div B&D');
                app.dataStruct(cnt+2).name = 'Cube Divided by Blur&Divide';
                
                app.DropDown.Items{end+1} = app.dataStruct(cnt+1).name;
                app.ListBox.Items = app.dataStruct(indx+1).bands;
                app.DropDown.Value = app.dataStruct(cnt+1).name;
                
                app.DropDown.Items{end+1} = app.dataStruct(cnt+2).name;
                %app.ListBox.Items = app.dataStruct(indx+2).bands;
                %app.DropDown.Value = app.dataStruct(cnt+2).name;
                
                app.dataStruct(1).name = 'currentImage';
                app.dataStruct(1).data = BandD_cube(:,:,1);
                
                app.RedTextArea.Value = strcat(string(band),' B&D');
                
                app.dataStruct(1).data = BandD_cube(:,:,1);
                app.dataStruct(1).bands = 'Band 1: B&D';
                set(app.UIFigure, 'pointer', 'arrow');
 
                app.BrightnessSlider.Value = 50;
                app.HueSlider.Enable = 'off';
                app.SaturationSlider.Enable = 'off';
                app.HueSliderLabel.Enable = 'off';
                app.SaturationSliderLabel.Enable = 'off';
                %app.PermuteRGBButton.Enable = 'off';
                app.RGBSwitch.Value = 'Gray';


%                end
            catch
                figure(app.UIFigure);
            end
            
            set(app.UIFigure, 'pointer', 'arrow');
            drawnow;


        end

        % Menu selected function: SpectralAngleMapperSAMMenu
        function SpectralAngleMapperSAMMenuSelected(app, event)

            
            try
                [indx,tf] = listdlg('PromptString',{'Select file for SAM.',...
                    'Note the image has to have 3 or more bands',...
                    '(a grayscale image will not work)'},...
                    'SelectionMode','single','ListString',app.DropDown.Items, 'ListSize',[160,100]);
    
                set(app.UIFigure, 'pointer', 'watch');
                dataCube = app.dataStruct(indx+1).data;
                data_trans = reshape(dataCube,[size(dataCube,1)*size(dataCube,2) size(dataCube,3)]);
                data_trans = data_trans';
    
                figure
                try       
                    temp = app.dataStruct(1).data;
                catch
                    temp = dataCube(:,:,round(size(dataCube,3)/2));
                end
    
                
                [temp]=stretchImage(temp,1.5);
                imshow(temp,[])
                %title('Select smaller rectangle to zoom into area where the final SAM selection will be made.')
                uiwait(msgbox('Select a smaller rectangle on the image which includes the area you are interested in. This will be enlarged for you to select the specific text / pigment area of interest.','Info'));
            
                roi = drawrectangle();
                
                coords = uint16(roi.Position);
                temp = temp(coords(2):coords(2)+coords(4),coords(1):coords(1)+coords(3));
                dataCube_subset = dataCube(coords(2):coords(2)+coords(4),coords(1):coords(1)+coords(3),:);
                imshow(temp,[])
                uiwait(msgbox('Select a small area to use for SAM calculation (Drag mouse within the area of interest - do not include background.','Info'));
            
                %title('Select small area to use for SAM calculation. Note, this needs to be pixels of one ink/pigment.')
    
                roi = drawfreehand();
                spectra = zeros([size(dataCube,3),size(roi.Position,1)]);
                for i = 1:size(roi.Position,1)
                    spectra(:,i) = dataCube_subset(uint16(roi.Position(i,2)),uint16(roi.Position(i,1)) ,:);
                end
                close(gcf);
    
                cnt = size(spectra,2);
                spectra_ave = sum(spectra,2)/cnt;
                
                img_sam = acosd(sum(data_trans.*repmat(spectra_ave,[1,size(data_trans,2)]),1)./...
                    (norm(spectra_ave)*(sqrt(sum(data_trans.^2,1)))));
                
                
                img_reshape = reshape(img_sam,[size(dataCube,1),size(dataCube,2)]);
                img_reshape = (img_reshape - min(img_reshape(:)))/(max(img_reshape(:)) - min(img_reshape(:)));
                img_reshape = 1-img_reshape;
    
                set(app.UIFigure, 'pointer', 'arrow');
    
                % display result
                set(app.UIAxes,'visible','on');
                imshow(img_reshape,'parent',app.UIAxes);
                %impixelinfo;
                
                app.RedTextArea.Value = 'SAM';
                    
                app.dataStruct(1).data = img_reshape;
                app.dataStruct(1).bands = 'SAM';
                
                app.BrightnessSlider.Value = 50;
                app.HueSlider.Enable = 'off';
                app.SaturationSlider.Enable = 'off';
                app.HueSliderLabel.Enable = 'off';
                app.SaturationSliderLabel.Enable = 'off';
                %app.PermuteRGBButton.Enable = 'off';
                app.RGBSwitch.Value = 'Gray';
                app.Sam = 1;
                
                
            catch
                ;
            end
            
            app.ThresholdSlider.Enable = 'on';

        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            closereq(); 
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            
                                   
            value = 1 / 100 * app.BrightnessSlider.Value;
            %value = 1-value;
            
            current_image = app.dataStruct(1).data;


            min_img = min(current_image(:));
            max_img = max(current_image(:));
            %steps = (max_img - min_img);
            
            RGBgray_selection = app.RGBSwitch.Value;
            
            if strcmp(RGBgray_selection,'Gray')
                if value < 0.5
                    img = imadjust(current_image,[min_img+(1-2*value)-0.001,max_img],[]);
                else
                    img = imadjust(current_image,[min_img,max_img-(2*value-1)+0.001],[]);
                end
                
            else
                current_image = app.dataStruct(1).data;
            
                h_value = 1 + (50 - app.HueSlider.Value)/100;
                s_value = 1 + (50 - app.SaturationSlider.Value)/100;
                v_value = 1 + (app.BrightnessSlider.Value)/100;
                
                img = HSV_changes(current_image, h_value, s_value, v_value);
                

            end
                      
            % display result
            set(app.UIAxes,'visible','on');
            imshow(img,'parent',app.UIAxes);
            
            %app.dataStruct(1).data = img;
            app.selected_bands_image = img;
            %app.dataStruct(1).bands = strcat(string(band),' stretched');
            
            
        end

        % Value changed function: HueSlider
        function HueSliderValueChanged(app, event)
            
            
            current_image = app.dataStruct(1).data;
            
            h_value = 1 + (50 - app.HueSlider.Value)/100;
            s_value = 1 + (50 - app.SaturationSlider.Value)/100;
            v_value = 1 + (app.BrightnessSlider.Value)/100;
                      
            
            % make changes
            img = HSV_changes(current_image, h_value, s_value, v_value);
            
            % display result
            set(app.UIAxes,'visible','on');
            imshow(img,'parent',app.UIAxes);
            
            app.selected_bands_image = img;


        end

        % Value changed function: SaturationSlider
        function SaturationSliderValueChanged(app, event)
            
            
            current_image = app.dataStruct(1).data;
            
            h_value = 1 + (50 - app.HueSlider.Value)/100;
            s_value = 1 + (50 - app.SaturationSlider.Value)/100;
            v_value = 1 + (app.BrightnessSlider.Value)/100;
            
            
            img = HSV_changes(current_image, h_value, s_value, v_value);
            
            % display result
            set(app.UIAxes,'visible','on');
            imshow(img,'parent',app.UIAxes);
            
            app.selected_bands_image = img;
            
        end

        % Menu selected function: CloseallMenu
        function CloseallMenuSelected(app, event)
            
            delete(app.UIFigure); %close the app
            SpectralAnalysisApp; %re-open it again
            
        end

        % Callback function
        function EdgeEnhancementMenuSelected(app, event)
            
        end

        % Menu selected function: CropMenu
        function CropMenuSelected(app, event)
            
            
            if ~strcmp(app.DropDown.Items{end},'Current displayed image')
                app.DropDown.Items{end+1} = 'Current displayed image';
            end
            
            [indx,tf] = listdlg('PromptString',{'Select file to crop'},...
                'SelectionMode','single','ListString',app.DropDown.Items,'ListSize',[300,160]);
            
            
            if tf == 1
                
                set(app.UIFigure, 'pointer', 'watch');
                drawnow;
                figure(app.UIFigure)
            
                if size(app.DropDown.Items,2) == indx
                    displayed = 1;
                    if isempty(app.selected_bands_image)
                        dataCube = app.dataStruct(1).data;
                        cnt = 1;
                    else
                        dataCube = app.selected_bands_image;
                        cnt = 1;
                    end
                else
                    displayed = 0;
                    dataCube = app.dataStruct(indx+1).data;
                    cnt = indx + 1;
                end
            
               app.DropDown.Items(end) = []; 
            
               num_files = size(dataCube,3);
                
               figure
               if num_files == 3
                   temp = dataCube;
               else 
                   temp = dataCube(:,:,round(num_files/2));
                   [temp]=stretchImage(temp, 1.5);
               end
               
               imshow(temp,[]) 
                
               % select smaller area for document
               message = sprintf('Select document only - rectangle');
               uiwait(msgbox(message));
    
               hFH = imrect();
               xy = hFH.getPosition;
               close(gcf)
    
               xy = floor(xy);
               
               row_end = xy(2)+xy(4);
               col_end = xy(1)+xy(3);
               
               if row_end > size(temp,1)
                   row_end = size(temp,1);
               end
               if col_end > size(temp,2)
                   col_end = size(temp,2);
               end
               
               dataCube_temp = dataCube(xy(2):row_end,xy(1):col_end,:);
               
               if num_files == 3
                   current_image = dataCube_temp;
               else
                   current_image = dataCube_temp(:,:,1);
               end
               
               app.dataStruct(1).data = current_image;
               %app.dataStruct(indx+1).bands = app.dataStruct(indx+1).bands;
               if displayed == 0
                   app.DropDown.Items{end+1} = [app.dataStruct(cnt).name,'_cropped_',num2str(app.crop_counter)]; 
                   app.DropDown.Value = [app.dataStruct(cnt).name,'_cropped_',num2str(app.crop_counter)]; 
                   app.dataStruct(end+1).data = dataCube_temp;
                   app.dataStruct(end).bands = [app.dataStruct(cnt).bands,'_cropped'];
                   app.dataStruct(end).name = [app.dataStruct(cnt).name,'_cropped_',num2str(app.crop_counter)];
               end 
   
               app.dataStruct(1).name = 'currentImage';
               app.dataStruct(1).data = current_image;
    
               set(app.UIAxes,'visible','on');
               imshow(current_image,'parent',app.UIAxes);
               
               app.RedTextArea.Value = app.dataStruct(1).bands(1);
               if num_files == 3
                   app.GreenTextArea.Value = app.dataStruct(1).bands(2);
                   app.BlueTextArea.Value = app.dataStruct(1).bands(3);
               else 
                   app.RGBSwitch.Value = 'Gray';
                   app.GreenTextArea.Value = 'Green';
                   app.BlueTextArea.Value = 'Blue';
               end
               
%                app.listbox_selection = [];
%                app.listbox_selection(1,1) = cnt+1;
%                app.listbox_selection(1,2) = 1;
                
               set(app.UIFigure, 'pointer', 'arrow');
               
               app.crop_counter = app.crop_counter + 1;
            end
            
        end

        % Menu selected function: MaximumNoiseFractionMNFMenu
        function MaximumNoiseFractionMNFMenuSelected(app, event)
            
            try
                cnt = length(app.dataStruct);
                
                [indx,~] = listdlg('PromptString',{'Select file for MNF'},...
                    'SelectionMode','single','ListString',app.DropDown.Items, 'ListSize',[250,100]);
    
                set(app.UIFigure, 'pointer', 'watch');
                drawnow;
                figure(app.UIFigure)
                
                dataCube = app.dataStruct(indx+1).data;
                
                 [indx2,~] = listdlg('PromptString',{'Select bands to use for MNF. Hold shift / cntr to select multiple bands.'},...
                'SelectionMode','multiple','ListString',app.dataStruct(indx+1).bands, 'ListSize',[160,100]);
            
                dataCube = dataCube(:,:,indx2);
                bands_to_keep = size(dataCube,3);


                dataMNF = hypermnf(dataCube,bands_to_keep,'MeanCentered',1);
                
                bandsMNF = {};
                for i = 1:bands_to_keep
                   bandsMNF{i} = ['MNF band ', num2str(i)]; 
                   temp = dataMNF(:,:,i);
                   dataMNF(:,:,i) = (temp - min(temp(:)))/(max(temp(:))-min(temp(:)));
                end
                
                [dataMNF]=stretchImage(dataMNF, 1.5);
                current_image = dataMNF(:,:,1);
                
                if app.MNF_counter == 1
                   app.dataStruct(cnt+1).name = ['MNF_',app.dataStruct(indx+1).name];
                   app.MNF_counter = app.MNF_counter + 1;
                else
                   app.dataStruct(cnt+1).name = ['MNF_',num2str(app.MNF_counter),'_',app.dataStruct(indx+1).name];
                   app.MNF_counter = app.MNF_counter + 1;
                end 


                app.dataStruct(cnt+1).data = dataMNF;
                app.dataStruct(cnt+1).bands = bandsMNF;
                
                app.DropDown.Items{end+1} = app.dataStruct(cnt+1).name;
                app.ListBox.Items = bandsMNF;
                app.DropDown.Value = app.dataStruct(cnt+1).name;
                
                app.dataStruct(1).name = 'currentImage';
                app.dataStruct(1).data = current_image;
    
                set(app.UIAxes,'visible','on');
                imshow(current_image,'parent',app.UIAxes);
                
                app.RGBSwitch.Value = 'Gray';
                app.RedTextArea.Value = bandsMNF{1};
                app.GreenTextArea.Value = 'Green';
                app.BlueTextArea.Value = 'Blue';
                
                app.listbox_selection = [];
                app.listbox_selection(1,1) = cnt+1;
                app.listbox_selection(1,2) = 1;
                app.listbox_value_temp = bandsMNF{1};
            catch
                ;
            end
            
            set(app.UIFigure, 'pointer', 'arrow');
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            listbox_selection = app.listbox_selection;
            
            % get index of selected file - add one since first in
            % dataStruct is current_image
            value = app.DropDown.Value;
            list = app.DropDown.Items;
            idx_file = find(strcmp(value,list)) + 1;
            
            if size(listbox_selection,1) == 1
                % get index of selected band
                value = app.listbox_value_temp;
                list = app.ListBox.Items;
                idx_band = find(strcmp(value,list));
                if idx_band == size(list,2)
                    idx_band = 1;
                else
                    idx_band = idx_band+1;
                end
                          
                listbox_selection = [];
                listbox_selection(1,1) = idx_file;
                listbox_selection(1,2) = idx_band;
                app.RedTextArea.Value = list(idx_band); 
                app.listbox_value_temp = list(idx_band);
            
            else
                order = app.nextButtonOrder;
                
                rgbPermute = [1,3,2;2,3,1;1,3,2;3,1,2;1,3,2];
                list = app.ListBox.Items;
  
                R = listbox_selection(1,2);
                G = listbox_selection(2,2);
                B = listbox_selection(3,2);
                listbox_selection(rgbPermute(order,1),2) = R;
                listbox_selection(rgbPermute(order,2),2) = G;
                listbox_selection(rgbPermute(order,3),2) = B;
                
                R = listbox_selection(1,1);
                G = listbox_selection(2,1);
                B = listbox_selection(3,1);
                listbox_selection(rgbPermute(order,1),1) = R;
                listbox_selection(rgbPermute(order,2),1) = G;
                listbox_selection(rgbPermute(order,3),1) = B;


                textcolor = [app.RedTextArea.Value,app.GreenTextArea.Value,app.BlueTextArea.Value];
        
                app.RedTextArea.Value = textcolor{rgbPermute(order,1)}; 
                app.GreenTextArea.Value = textcolor{rgbPermute(order,2)};
                app.BlueTextArea.Value = textcolor{rgbPermute(order,3)}; 
            end
                
            app.listbox_selection = listbox_selection;
            
            app.ListBox.Value = {};
            if app.nextButtonOrder == 5
               app.nextButtonOrder = 1;
            else
               app.nextButtonOrder = app.nextButtonOrder + 1; 
            end
            DisplayResetButtonPushed(app);
            
            
        end

        % Button pushed function: DisplayThumbnailButton
        function DisplayThumbnailButtonPushed(app, event)
            try
                app.dataStruct = get_selected_image_from_dataStruct(app.listbox_selection,app.dataStruct);
                
                current_image = app.dataStruct(1).data;
                
                imshow(current_image,'parent',app.UIAxes2);
                
                if size(current_image,3) == 1
                    app.GreenTextArea.Value = 'Green';
                    app.BlueTextArea.Value = 'Blue';
                    app.RGBSwitch.Value = 'Gray';
                end
                
                app.selected_bands_image = current_image;
                app.thumbnail_image = current_image;
            catch
                uiwait(msgbox('Remember to select the bands to display','OK','help'))
            end
            
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
             listbox_selection = app.listbox_selection;
            
            % get index of selected file - add one since first in
            % dataStruct is current_image
            value = app.DropDown.Value;
            list = app.DropDown.Items;
            idx_file = find(strcmp(value,list)) + 1;
            
            if size(listbox_selection,1) == 1
                % get index of selected band
                value = app.listbox_value_temp;
                list = app.ListBox.Items;
                idx_band = find(strcmp(value,list));
                if idx_band == 1
                    idx_band = size(list,2);
                else
                    idx_band = idx_band-1;
                end
                          
                listbox_selection = [];
                listbox_selection(1,1) = idx_file;
                listbox_selection(1,2) = idx_band;
                app.RedTextArea.Value = list(idx_band); 
                app.listbox_value_temp = list(idx_band);
            
            else
                order = app.nextButtonOrder;
                
                rgbPermute = [1,3,2;2,3,1;1,3,2;3,1,2;1,3,2];
                list = app.ListBox.Items;
  
                R = listbox_selection(1,2);
                G = listbox_selection(2,2);
                B = listbox_selection(3,2);
                listbox_selection(rgbPermute(order,1),2) = R;
                listbox_selection(rgbPermute(order,2),2) = G;
                listbox_selection(rgbPermute(order,3),2) = B;
        
                app.RedTextArea.Value = list(listbox_selection(1,2));
                app.GreenTextArea.Value = list(listbox_selection(2,2));
                app.BlueTextArea.Value = list(listbox_selection(3,2));
            end
                
            app.listbox_selection = listbox_selection;
            
            app.ListBox.Value = {};
            if app.nextButtonOrder == 5
               app.nextButtonOrder = 1;
            else
               app.nextButtonOrder = app.nextButtonOrder + 1; 
            end
            DisplayResetButtonPushed(app);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {654, 654};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {252, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: ImageMath
        function ImageMathMenuSelected(app, event)
            prompt = {'Enter equation. Use letters for variables (e.g., (0.3*(A) + 0.7*(B)) ./ B). NOTE, place a dot (.) before any divide (/) or multiply (*) operation:'};
            dlgtitle = 'Enter equation';
            dims = [1 60];
            definput = {'(A-B)./(A + B)'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            equation = char(answer);
            
            if ~ isempty(answer)            
                T = isletter(equation);
                k = find(T==1);
                
                str = '';
                vars = {};
                
                for i = 1:size(k,2)
                    vars{i,1} = equation(k(i));
                end
                
                vars = unique(vars);
                eq = '@(';
                
                for i = 1:size(vars,1)
                    if i < size(vars,1)
                        eq = [eq , vars{i,1},','];
                    else
                        eq = [eq ,vars{i,1},')', equation];
                    end
                end
                
                bandMathFunction = str2func(eq);
                app.BandMathData = BandMath(app,vars,bandMathFunction,app.dataStruct);
                app.BrightnessSlider.Value = 50;
                app.HueSlider.Enable = 'off';
                app.SaturationSlider.Enable = 'off';
                app.HueSliderLabel.Enable = 'off';
                app.SaturationSliderLabel.Enable = 'off';
            end

        end

        % Button pushed function: UndockButton
        function UndockButtonPushed(app, event)
            try
                current_image = app.thumbnail_image;                
                figure()
                imshow(current_image);
                               
            catch
                app.dataStruct = get_selected_image_from_dataStruct(app.listbox_selection,app.dataStruct);
                
                current_image = app.dataStruct(1).data;
                
                figure()
                imshow(current_image);
                
                if size(current_image,3) == 1
                    app.GreenTextArea.Value = 'Green';
                    app.BlueTextArea.Value = 'Blue';
                    app.RGBSwitch.Value = 'Gray';
                end
                
                app.selected_bands_image = current_image;
            end
        end

        % Menu selected function: GaussianMenu
        function GaussianMenuSelected(app, event)
            
            app.SaturationSlider.Value = 50;
            app.HueSlider.Value = 50;
            app.BrightnessSlider.Value = 50;
            
            if isempty(app.selected_bands_image)
                dataCube = app.dataStruct(1).data;
            else
                dataCube = app.selected_bands_image;
            end
                       
            for i = 1:size(dataCube,3)
                data = dataCube(:,:,i);
                mu = mean(data(:));
                
                sigma = std(data(:));
                data(data > mu + 3*sigma) = 1;
                data(data < mu - 3*sigma) = 0;
                
                sigma = std(data(:));
                noise = normrnd(0.5,sigma,size(data,1),size(data,2));
                
                data = imhistmatch(data,noise);
                dataCube(:,:,i) = data;
            end
            
            
%             ENVI performs the following steps for Gaussian stretch:
% 
%             Sets the data mean value to a screen value of 127.
%             Sets the data value that is three standard deviations below the mean value to a screen value of 0.
%             Sets the data value that is three standard deviations above the mean value to a screen value of 255.
% 
%             Intermediate values are assigned screen values using a Gaussian curve.
            
            app.selected_bands_image = dataCube;
            app.dataStruct(1).data = dataCube;
            
            % display result
            set(app.UIAxes,'visible','on');
            imshow(dataCube,'parent',app.UIAxes);
            
        end

        % Menu selected function: HistogramequalizationMenu
        function HistogramequalizationMenuSelected(app, event)
            app.SaturationSlider.Value = 50;
            app.HueSlider.Value = 50;
            app.BrightnessSlider.Value = 50;
            
            if isempty(app.selected_bands_image)
                dataCube = app.dataStruct(1).data;
            else
                dataCube = app.selected_bands_image;
            end

            for i = 1:size(dataCube,3)
                dataCube(:,:,i) = adapthisteq(dataCube(:,:,i));
            end
        
            app.selected_bands_image = dataCube;
            app.dataStruct(1).data = dataCube;
            
            % display result
            set(app.UIAxes,'visible','on');
            imshow(dataCube,'parent',app.UIAxes);
            
        end

        % Menu selected function: ForTheBrave
        function ForTheBraveMenuSelected(app, event)
            
        end

        % Menu selected function: SharpenMenu
        function SharpenMenuSelected(app, event)
            if isempty(app.selected_bands_image)
                dataCube = app.dataStruct(1).data;
            else
                dataCube = app.selected_bands_image;
            end
                       
            dataCube = imsharpen(dataCube,'Radius',3,'Amount',4);
            
            app.selected_bands_image = dataCube;
            
            % display result
            set(app.UIAxes,'visible','on');
            imshow(dataCube,'parent',app.UIAxes);
            
            app.InformationBox.Value = ['Sharpening completed.'];
        end

        % Menu selected function: SmoothBlurMenu
        function SmoothBlurMenuSelected(app, event)
            if isempty(app.selected_bands_image)
                dataCube = app.dataStruct(1).data;
            else
                dataCube = app.selected_bands_image;
            end
            
            for i = 1:size(dataCube,3)
                dataCube(:,:,i) = imgaussfilt(dataCube(:,:,i),4);
            end
            
            app.selected_bands_image = dataCube;
            
            % display result
            set(app.UIAxes,'visible','on');
            imshow(dataCube,'parent',app.UIAxes);
            
            app.InformationBox.Value = ['Smoothing completed.'];
            
        end

        % Menu selected function: CurtainViewMenu
        function CurtainViewMenuSelected(app, event)
            CurtainView(app)
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            
            img = app.dataStruct(1).data;
            
            thresh = median(img(:));
            
            img(img > 0.5-thresh - (0.5-value/100)) = 0;
            img(img ~= 0 ) = 1;


            % display result
            set(app.UIAxes,'visible','on');
            imshow(img,'parent',app.UIAxes);
            
            app.selected_bands_image = img;
            
        end

        % Menu selected function: LinearStretchMenu
        function LinearStretchMenuSelected(app, event)
            if isempty(app.selected_bands_image)
                dataCube = app.dataStruct(1).data;
            else
                dataCube = app.selected_bands_image;
            end
            
            prompt = {'Enter % for linear stretch (between 1 and 20:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'2'};
            thresh = inputdlg(prompt,dlgtitle,dims,definput)
            
            stretched=stretchImage(dataCube, str2double(char(thresh)));
            
            app.selected_bands_image = stretched;
            
            % display result
            set(app.UIAxes,'visible','on');
            imshow(stretched,'parent',app.UIAxes);
            
            app.InformationBox.Value = ['Streching.'];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.DisplayResetButton = uibutton(app.GridLayout2, 'push');
            app.DisplayResetButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayResetButtonPushed, true);
            app.DisplayResetButton.Tooltip = {'Displays the selected bands, and reset the Hue, Saturation and Brightness sliders.'};
            app.DisplayResetButton.Text = 'Display / Reset';
            app.BlueTextArea = uitextarea(app.GridLayout2);
            app.BlueTextArea.Tag = 'blue_textbox';
            app.BlueTextArea.Editable = 'off';
            app.BlueTextArea.Value = {'Blue'};
            app.GreenTextArea = uitextarea(app.GridLayout2);
            app.GreenTextArea.Tag = 'green_textbox';
            app.GreenTextArea.Editable = 'off';
            app.GreenTextArea.Value = {'Green'};
            app.RedTextArea = uitextarea(app.GridLayout2);
            app.RedTextArea.Tag = 'red_textbox';
            app.RedTextArea.Editable = 'off';
            app.RedTextArea.Value = {'Red'};
            app.RGBSwitch = uiswitch(app.GridLayout2, 'slider');
            app.RGBSwitch.Items = {'RGB', 'Gray'};
            app.RGBSwitch.ValueChangedFcn = createCallbackFcn(app, @RGBSwitchValueChanged, true);
            app.RGBSwitch.Tag = 'RGBGray_toggle';
            app.RGBSwitch.Tooltip = {'Changes the display options to either a grayscale image, or a color image.'};
            app.RGBSwitch.Value = 'RGB';
            app.ListBox = uilistbox(app.GridLayout2);
            app.ListBox.Items = {'Bands'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Tag = 'current_bands_listbox';
            app.ListBox.Tooltip = {''};
            app.ListBox.Value = 'Bands';
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {'Filename'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.Tag = 'open_files_dropdown';
            app.DropDown.Value = 'Filename';
            app.HueSliderLabel = uilabel(app.GridLayout2);
            app.HueSliderLabel.Text = 'Hue';
            app.HueSlider = uislider(app.GridLayout2);
            app.HueSlider.MajorTicks = [];
            app.HueSlider.MajorTickLabels = {};
            app.HueSlider.ValueChangedFcn = createCallbackFcn(app, @HueSliderValueChanged, true);
            app.HueSlider.MinorTicks = [];
            app.HueSlider.Tooltip = {'Changes the color appearance of the image. Hue changes can only be made in RGB images.'};
            app.HueSlider.Value = 50;
            app.SaturationSliderLabel = uilabel(app.GridLayout2);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.GridLayout2);
            app.SaturationSlider.MajorTicks = [];
            app.SaturationSlider.MajorTickLabels = {'0'};
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @SaturationSliderValueChanged, true);
            app.SaturationSlider.MinorTicks = [];
            app.SaturationSlider.Tooltip = {'Changes the intensity of color in the image. Saturation can only be changed in RGB images.'};
            app.SaturationSlider.Value = 50;
            app.BrightnessSliderLabel = uilabel(app.GridLayout2);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.GridLayout2);
            app.BrightnessSlider.MajorTicks = [];
            app.BrightnessSlider.MajorTickLabels = {};
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [];
            app.BrightnessSlider.Tooltip = {'Changes the brightness of the image.'};
            app.BrightnessSlider.Value = 50;
            app.NextButton = uibutton(app.GridLayout2, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tooltip = {'Display next grayscale or permutaion of color bands'};
            app.NextButton.Text = '>>';
            app.DisplayThumbnailButton = uibutton(app.GridLayout2, 'push');
            app.DisplayThumbnailButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayThumbnailButtonPushed, true);
            app.DisplayThumbnailButton.Tooltip = {'Display for reference. E.g. showing true color image. '};
            app.DisplayThumbnailButton.Text = 'Display Thumbnail';
            app.PrevButton = uibutton(app.GridLayout2, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = '<<';
            app.UndockButton = uibutton(app.GridLayout2, 'push');
            app.UndockButton.ButtonPushedFcn = createCallbackFcn(app, @UndockButtonPushed, true);
            app.UndockButton.Text = 'Undock';
            app.ThresholdSliderLabel = uilabel(app.GridLayout2);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.GridLayout2);
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.MajorTickLabels = {};
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSlider.Tooltip = {'Changes the brightness of the image.'};
            app.ThresholdSlider.Value = 50;
            app.InformationBox = uitextarea(app.GridLayout2);
            app.InformationBox.Tag = 'green_textbox';
            app.InformationBox.Editable = 'off';
            app.InformationBox.Tooltip = {'Information will display progress of processes.'};
            app.InformationBox.Value = {'Information box'};
            app.UIAxes2 = uiaxes(app.GridLayout2);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Tag = 'image_display';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.BuildCubeMenu = uimenu(app.FileMenu);
            app.BuildCubeMenu.MenuSelectedFcn = createCallbackFcn(app, @BuildCubeMenuSelected, true);
            app.BuildCubeMenu.Text = 'Build Cube';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.ENVIMenu = uimenu(app.SaveMenu);
            app.ENVIMenu.MenuSelectedFcn = createCallbackFcn(app, @ENVIMenuSelected, true);
            app.ENVIMenu.Text = 'ENVI';
            app.JPGMenu = uimenu(app.SaveMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.PNGMenu = uimenu(app.SaveMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.TIFMenu = uimenu(app.SaveMenu);
            app.TIFMenu.MenuSelectedFcn = createCallbackFcn(app, @TIFMenuSelected, true);
            app.TIFMenu.Text = 'TIF';
            app.CloseallMenu = uimenu(app.FileMenu);
            app.CloseallMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseallMenuSelected, true);
            app.CloseallMenu.Text = 'Close all';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.EnhanceMenu = uimenu(app.EditMenu);
            app.EnhanceMenu.Text = 'Enhance';
            app.GaussianMenu = uimenu(app.EnhanceMenu);
            app.GaussianMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianMenuSelected, true);
            app.GaussianMenu.Text = 'Gaussian';
            app.HistogramequalizationMenu = uimenu(app.EnhanceMenu);
            app.HistogramequalizationMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramequalizationMenuSelected, true);
            app.HistogramequalizationMenu.Text = 'Histogram equalization';
            app.SharpenMenu = uimenu(app.EditMenu);
            app.SharpenMenu.MenuSelectedFcn = createCallbackFcn(app, @SharpenMenuSelected, true);
            app.SharpenMenu.Text = 'Sharpen';
            app.SmoothBlurMenu = uimenu(app.EditMenu);
            app.SmoothBlurMenu.MenuSelectedFcn = createCallbackFcn(app, @SmoothBlurMenuSelected, true);
            app.SmoothBlurMenu.Text = 'Smooth / Blur';
            app.RotateMenu = uimenu(app.EditMenu);
            app.RotateMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateMenuSelected, true);
            app.RotateMenu.Text = 'Rotate';
            app.InvertMenu = uimenu(app.EditMenu);
            app.InvertMenu.MenuSelectedFcn = createCallbackFcn(app, @InvertMenuSelected, true);
            app.InvertMenu.Text = 'Invert';
            app.CropMenu = uimenu(app.EditMenu);
            app.CropMenu.MenuSelectedFcn = createCallbackFcn(app, @CropMenuSelected, true);
            app.CropMenu.Text = 'Crop';
            app.LinearStretchMenu = uimenu(app.EditMenu);
            app.LinearStretchMenu.MenuSelectedFcn = createCallbackFcn(app, @LinearStretchMenuSelected, true);
            app.LinearStretchMenu.Text = 'Linear Stretch';
            app.AnalysisMenu = uimenu(app.UIFigure);
            app.AnalysisMenu.Text = 'Analysis';
            app.PrincipalComponentAnalysisPCAMenu = uimenu(app.AnalysisMenu);
            app.PrincipalComponentAnalysisPCAMenu.MenuSelectedFcn = createCallbackFcn(app, @PrincipalComponentAnalysisPCAMenuSelected, true);
            app.PrincipalComponentAnalysisPCAMenu.Text = 'Principal Component Analysis (PCA)';
            app.MaximumNoiseFractionMNFMenu = uimenu(app.AnalysisMenu);
            app.MaximumNoiseFractionMNFMenu.MenuSelectedFcn = createCallbackFcn(app, @MaximumNoiseFractionMNFMenuSelected, true);
            app.MaximumNoiseFractionMNFMenu.Text = 'Maximum Noise Fraction (MNF)';
            app.SpectralAngleMapperSAMMenu = uimenu(app.AnalysisMenu);
            app.SpectralAngleMapperSAMMenu.MenuSelectedFcn = createCallbackFcn(app, @SpectralAngleMapperSAMMenuSelected, true);
            app.SpectralAngleMapperSAMMenu.Tooltip = {'SAM displays pigments similar to those selected. '};
            app.SpectralAngleMapperSAMMenu.Text = 'Spectral Angle Mapper (SAM)';
            app.BlurandDivideMenu = uimenu(app.AnalysisMenu);
            app.BlurandDivideMenu.MenuSelectedFcn = createCallbackFcn(app, @BlurandDivideMenuSelected, true);
            app.BlurandDivideMenu.Text = 'Blur and Divide';
            app.CurtainViewMenu = uimenu(app.AnalysisMenu);
            app.CurtainViewMenu.MenuSelectedFcn = createCallbackFcn(app, @CurtainViewMenuSelected, true);
            app.CurtainViewMenu.Text = 'Curtain View';
            app.ForTheBrave = uimenu(app.UIFigure);
            app.ForTheBrave.MenuSelectedFcn = createCallbackFcn(app, @ForTheBraveMenuSelected, true);
            app.ForTheBrave.Text = 'For the brave';
            app.ImageMath = uimenu(app.ForTheBrave);
            app.ImageMath.MenuSelectedFcn = createCallbackFcn(app, @ImageMathMenuSelected, true);
            app.ImageMath.Text = 'Image math';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectralAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end